classdef DispersionGraphing < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        PlotButton                   matlab.ui.control.Button
        WavemodeButtonGroup          matlab.ui.container.ButtonGroup
        ShearHorizontalButton        matlab.ui.control.RadioButton
        FlexuralButton               matlab.ui.control.RadioButton
        LongitudinalButton           matlab.ui.control.RadioButton
        TorsionalButton              matlab.ui.control.RadioButton
        AllButton                    matlab.ui.control.RadioButton
        YAxisListBoxLabel            matlab.ui.control.Label
        YAxisListBox                 matlab.ui.control.ListBox
        UIAxes                       matlab.ui.control.UIAxes
        YAxisUpperLimEditFieldLabel  matlab.ui.control.Label
        YAxisUpperLimEditField       matlab.ui.control.NumericEditField
        YAxisLowerLimEditFieldLabel  matlab.ui.control.Label
        YAxisLowerLimEditField       matlab.ui.control.NumericEditField
        LoadButton                   matlab.ui.control.Button
    end

    
    properties (Access = private)
        SH      %Shear Horizontal Struct
        Long    %Longitudinal Struct
        Tors    %Torsional Struct
        Flex    %Flexural Struct
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            
                                 
            if isequal('Phase Velocity',app.YAxisListBox.Value) == 1
                field = 'PhaseVel';
            elseif isequal('Energy Velocity',app.YAxisListBox.Value) == 1
                field = 'EnergyVelocityWeld';
            elseif isequal('Attenuation',app.YAxisListBox.Value) == 1
                field = 'Attenuation';
            elseif isequal('Wavenumber',app.YAxisListBox.Value) == 1
                field = 'WaveNumber';
            elseif isequal('Wavelength',app.YAxisListBox.Value) == 1
                field = 'WaveLength';
            end


            if app.AllButton.Value == true
                
                count = 1;
                
                %clearing figure
                cla(app.UIAxes)
                
                order = 15;
                
                %%Shear Horizontal
                for i = 1:length(app.SH)  
                    [p,~,mu] = polyfit(app.SH(i).Frequency,app.SH(i).(field), order);
                    f = polyval(p,app.SH(i).Frequency,[],mu);
                    plot(app.UIAxes,app.SH(i).Frequency/1e3,f,'-d','LineWidth',2)
                    hold(app.UIAxes,'on')
                    leg{count} = strcat('SH',num2str(app.SH(i).Order));
                    count = count + 1;
                end
                %Longitudinal
                for i = 1:length(app.Long)
                    [p,~,mu] = polyfit(app.Long(i).Frequency,app.Long(i).(field), order);
                    f = polyval(p,app.Long(i).Frequency,[],mu);
                    hold(app.UIAxes,'on')
                    plot(app.UIAxes,app.Long(i).Frequency/1e3,f,'-d','LineWidth',2)
                    leg{count} = strcat('L',num2str(app.Long(i).Order));
                    count = count + 1;
                end
                %Flexural
                for i = 1:length(app.Flex)
                    [p,~,mu] = polyfit(app.Flex(i).Frequency,app.Flex(i).(field), order);
                    f = polyval(p,app.Flex(i).Frequency,[],mu);
                    hold(app.UIAxes,'on')
                    plot(app.UIAxes,app.Flex(i).Frequency/1e3,f,'-d','LineWidth',2)
                    leg{count} = strcat('F',num2str(app.Flex(i).Order));
                    count = count + 1;
                end
                %Torsional
                for i = 1:length(app.Tors)
                    [p,~,mu] = polyfit(app.Tors(i).Frequency,app.Tors(i).(field), order);
                    f = polyval(p,app.Tors(i).Frequency,[],mu);
                    hold(app.UIAxes,'on')
                    plot(app.UIAxes,app.Tors(i).Frequency/1e3,f,'-d','LineWidth',2)
                    leg{count} = strcat('F',num2str(app.Tors(i).Order));
                    count = count + 1;
                end
                
                if isequal('Phase Velocity',app.YAxisListBox.Value) == 1
                    app.UIAxes.YLabel.String  = 'Phase Velocity (m/s)';
                    app.UIAxes.Title.String = 'Frequency V Phase Velocity';
                 elseif isequal('Energy Velocity',app.YAxisListBox.Value) == 1
                    app.UIAxes.YLabel.String  = 'Energy Velocity (m/s)';
                    app.UIAxes.Title.String = 'Frequency V Energy Velocity';
                elseif isequal('Attenuation',app.YAxisListBox.Value) == 1
                    app.UIAxes.YLabel.String  = 'Attenuation (Np/m)';
                    app.UIAxes.Title.String = 'Frequency V Attenuation';
                elseif isequal('Wavenumber',app.YAxisListBox.Value) == 1
                    app.UIAxes.YLabel.String  = 'Wavenumber (rads/m)';
                    app.UIAxes.Title.String = 'Frequency V Wavenumber';
                elseif isequal('Wavelength',app.YAxisListBox.Value) == 1
                    app.UIAxes.YLabel.String  = 'Wavelength (m)';
                    app.UIAxes.Title.String = 'Frequency V Wavelength';
                end
                
                app.UIAxes.XLabel.String = 'Frequency (KHz)';
                app.UIAxes.YLim = [app.YAxisLowerLimEditField.Value, app.YAxisUpperLimEditField.Value];
                app.UIAxes.XLim = [50, 760];
                legend(app.UIAxes,leg)
            elseif app.ShearHorizontalButton.Value == true
                
                count = 1;
                
                %clearing figure
                cla(app.UIAxes)
                         
                %%Shear Horizontal
                for i = 1:length(app.SH)
                    
                    order = 20;
%                     
%                     if length(app.SH(i).(field)) < 40
%                         order  = length(app.SH(i).(field));
%                     end
                    
                    [p,~,mu] = polyfit(app.SH(i).Frequency,app.SH(i).(field), order);
                    f = polyval(p,app.SH(i).Frequency,[],mu);
                    f = smooth(f,3);
                    plot(app.UIAxes,app.SH(i).Frequency/1e3,f,'-d','LineWidth',2)
                    hold(app.UIAxes,'on')
                    leg{count} = strcat('SH',num2str(app.SH(i).Order));
                    count = count + 1;
                    
                end
                
                if isequal('Phase Velocity',app.YAxisListBox.Value) == 1
                    app.UIAxes.YLabel.String  = 'Phase Velocity (m/s)';
                    app.UIAxes.Title.String = 'Frequency V Phase Velocity';
                 elseif isequal('Energy Velocity',app.YAxisListBox.Value) == 1
                    app.UIAxes.YLabel.String  = 'Energy Velocity (m/s)';
                    app.UIAxes.Title.String = 'Frequency V Energy Velocity';
                elseif isequal('Attenuation',app.YAxisListBox.Value) == 1
                    app.UIAxes.YLabel.String  = 'Attenuation (Np/m)';
                    app.UIAxes.Title.String = 'Frequency V Attenuation';
                elseif isequal('Wavenumber',app.YAxisListBox.Value) == 1
                    app.UIAxes.YLabel.String  = 'Wavenumber (rads/m)';
                    app.UIAxes.Title.String = 'Frequency V Wavenumber';
                elseif isequal('Wavelength',app.YAxisListBox.Value) == 1
                    app.UIAxes.YLabel.String  = 'Wavelength (m)';
                    app.UIAxes.Title.String = 'Frequency V Wavelength';
                end
                
                app.UIAxes.XLabel.String = 'Frequency (KHz)';
                app.UIAxes.YLim = [app.YAxisLowerLimEditField.Value, app.YAxisUpperLimEditField.Value];
                app.UIAxes.XLim = [50, 760];
                legend(app.UIAxes,leg)
                
            elseif app.LongitudinalButton.Value == true
                
                count = 1;
                
                %clearing figure
                cla(app.UIAxes)
                
                order = 10;
                
                %Longitudinal
                for i = 1:length(app.Long)
                    [p,~,mu] = polyfit(app.Long(i).Frequency,app.Long(i).(field), order);
                    f = polyval(p,app.Long(i).Frequency,[],mu);
                    hold(app.UIAxes,'on')
                    plot(app.UIAxes,app.Long(i).Frequency/1e3,f,'-d','LineWidth',2)
                    leg{count} = strcat('L',num2str(app.Long(i).Order));
                    count = count + 1;
                end
                
                if isequal('Phase Velocity',app.YAxisListBox.Value) == 1
                    app.UIAxes.YLabel.String  = 'Phase Velocity (m/s)';
                    app.UIAxes.Title.String = 'Frequency V Phase Velocity';
                elseif isequal('Energy Velocity',app.YAxisListBox.Value) == 1
                    app.UIAxes.YLabel.String  = 'Energy Velocity (m/s)';
                    app.UIAxes.Title.String = 'Frequency V Energy Velocity';
                elseif isequal('Attenuation',app.YAxisListBox.Value) == 1
                    app.UIAxes.YLabel.String  = 'Attenuation (Np/m)';
                    app.UIAxes.Title.String = 'Frequency V Attenuation';
                elseif isequal('Wavenumber',app.YAxisListBox.Value) == 1
                    app.UIAxes.YLabel.String  = 'Wavenumber (rads/m)';
                    app.UIAxes.Title.String = 'Frequency V Wavenumber';
                elseif isequal('Wavelength',app.YAxisListBox.Value) == 1
                    app.UIAxes.YLabel.String  = 'Wavelength (m)';
                    app.UIAxes.Title.String = 'Frequency V Wavelength';
                end
                
                app.UIAxes.XLabel.String = 'Frequency (KHz)';
                app.UIAxes.YLim = [app.YAxisLowerLimEditField.Value, app.YAxisUpperLimEditField.Value];
                app.UIAxes.XLim = [50, 760];
                legend(app.UIAxes,leg)
                
            elseif app.FlexuralButton.Value == true
                
                count = 1;
                
                %clearing figure
                cla(app.UIAxes)
                
                order = 10;
                
                %Flexural
                for i = 1:length(app.Flex)
                    [p,~,mu] = polyfit(app.Flex(i).Frequency,app.Flex(i).(field), order);
                    f = polyval(p,app.Flex(i).Frequency,[],mu);
                    hold(app.UIAxes,'on')
                    plot(app.UIAxes,app.Flex(i).Frequency/1e3,f,'-d','LineWidth',2)
                    leg{count} = strcat('F',num2str(app.Flex(i).Order));
                    count = count + 1;
                end
                
                if isequal('Phase Velocity',app.YAxisListBox.Value) == 1
                    app.UIAxes.YLabel.String  = 'Phase Velocity (m/s)';
                    app.UIAxes.Title.String = 'Frequency V Phase Velocity';
                elseif isequal('Energy Velocity',app.YAxisListBox.Value) == 1
                    app.UIAxes.YLabel.String  = 'Energy Velocity (m/s)';
                    app.UIAxes.Title.String = 'Frequency V Energy Velocity';
                elseif isequal('Attenuation',app.YAxisListBox.Value) == 1
                    app.UIAxes.YLabel.String  = 'Attenuation (Np/m)';
                    app.UIAxes.Title.String = 'Frequency V Attenuation';
                elseif isequal('Wavenumber',app.YAxisListBox.Value) == 1
                    app.UIAxes.YLabel.String  = 'Wavenumber (rads/m)';
                    app.UIAxes.Title.String = 'Frequency V Wavenumber';
                elseif isequal('Wavelength',app.YAxisListBox.Value) == 1
                    app.UIAxes.YLabel.String  = 'Wavelength (m)';
                    app.UIAxes.Title.String = 'Frequency V Wavelength';
                end
                
                app.UIAxes.XLabel.String = 'Frequency (KHz)';
                app.UIAxes.YLim = [app.YAxisLowerLimEditField.Value, app.YAxisUpperLimEditField.Value];
                app.UIAxes.XLim = [50, 760];
                legend(app.UIAxes,leg)              
                
            elseif app.TorsionalButton.Value == true
                
                count = 1;
                
                %clearing figure
                cla(app.UIAxes)
                
                order = 10;
                
                %Torsional
                for i = 1:length(app.Tors)
                    [p,~,mu] = polyfit(app.Tors(i).Frequency,app.Tors(i).(field), order);
                    f = polyval(p,app.Tors(i).Frequency,[],mu);
                    hold(app.UIAxes,'on')
                    plot(app.UIAxes,app.Tors(i).Frequency/1e3,f,'-d','LineWidth',2)
                    leg{count} = strcat('T',num2str(app.Tors(i).Order));
                    count = count + 1;
                end
                
                if isequal('Phase Velocity',app.YAxisListBox.Value) == 1
                    app.UIAxes.YLabel.String  = 'Phase Velocity (m/s)';
                    app.UIAxes.Title.String = 'Frequency V Phase Velocity';
                elseif isequal('Energy Velocity',app.YAxisListBox.Value) == 1
                    app.UIAxes.YLabel.String  = 'Energy Velocity (m/s)';
                    app.UIAxes.Title.String = 'Frequency V Energy Velocity';
                elseif isequal('Attenuation',app.YAxisListBox.Value) == 1
                    app.UIAxes.YLabel.String  = 'Attenuation (Np/m)';
                    app.UIAxes.Title.String = 'Frequency V Attenuation';
                elseif isequal('Wavenumber',app.YAxisListBox.Value) == 1
                    app.UIAxes.YLabel.String  = 'Wavenumber (rads/m)';
                    app.UIAxes.Title.String = 'Frequency V Wavenumber';
                elseif isequal('Wavelength',app.YAxisListBox.Value) == 1
                    app.UIAxes.YLabel.String  = 'Wavelength (m)';
                    app.UIAxes.Title.String = 'Frequency V Wavelength';
                end
                
                app.UIAxes.XLabel.String = 'Frequency (KHz)';
                app.UIAxes.YLim = [app.YAxisLowerLimEditField.Value, app.YAxisUpperLimEditField.Value];
                app.UIAxes.XLim = [50, 760];
                legend(app.UIAxes,leg)    
                
            end
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            %clearing all previous data
            %clear all
            f = waitbar(0,'Please wait...','Name','Loading SAFEM Data...',...
                'CreateCancelBtn','setappdata(gcbf,''canceling'',1)');
            
            setappdata(f,'canceling',0)
            
            waitbar(.33,f,'Loading your data');
            
            %Read in Data
            T = readtable('Results.xlsx');
            
            waitbar(.67,f,'Processing your data');
            
            %% Remove Duplicate Results
            comp = strcmp(T.WaveClassification,'None');
            T = T(comp == 0,:);
            
            %Converting Columns to numeric values
            %T.Order = str2double(T.Order);
            T.EigenValue = str2double(T.EigenValue);
            %% Removing Duplicate solutions
            eigen = round(T.EigenValue,3);
            x = unique(eigen);                  %finding uniques values in m array only
            
            %Logging all indexes of the first unique value
            indx = zeros(length(x),1);
            for i = 1:size(indx,1)
               indx(i,1) =  find( eigen(:,1) == x(i,1),1,'first');
            end
            %Sorting the index array in ascending Order
            indx = sort(indx);
            
            T = T(indx,:);
            
            %% Creata Mode Map & other variables
            key_set = {'Shear Horizontal','Longitudinal','Flexural','Torsional'};
            value_set = [0 1 2 3];
            mode_map = containers.Map(key_set,value_set);
            
            ModeMap = zeros(size(T,1),1);
            PhaseVel = zeros(size(T,1),1);
            Attenuation = zeros(size(T,1),1);
            WaveNumber = zeros(size(T,1),1);
            WaveLength = zeros(size(T,1),1);
            
            for ii = 1:size(T,1)
                ModeMap(ii) = mode_map(T.WaveClassification{ii});
                WaveNumber(ii) = real(T.EigenValue(ii));
                Attenuation(ii) = abs(imag(T.EigenValue(ii)));
                PhaseVel(ii) = 2*pi*T.Frequency(ii)/WaveNumber(ii);
                WaveLength(ii) = 2*pi/WaveNumber(ii);
            end
            
            T_wavemode = addvars(T,ModeMap,PhaseVel, Attenuation, WaveNumber, WaveLength);
            %clear ModeMap PhaseVel Attenuation WaveNumber WaveLength
            %% Subdividing table into individual tables 
            SH_table = T_wavemode(T_wavemode.ModeMap==0,:);
            Long_table = T_wavemode(T_wavemode.ModeMap==1,:);
            Flex_table = T_wavemode(T_wavemode.ModeMap==2,:);
            Tors_table = T_wavemode(T_wavemode.ModeMap==3,:);
            
            %Transposing data into structs based on classification
            [app.SH, app.Tors, app.Long, app.Flex] = fn_transpose_data(SH_table, Long_table, Tors_table, Flex_table);
            
            waitbar(1,f,'Finishing');
            
            delete(f)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.WavemodeButtonGroup = uibuttongroup(app.UIFigure);
            app.WavemodeButtonGroup.Title = 'Wavemode';
            app.ShearHorizontalButton = uiradiobutton(app.WavemodeButtonGroup);
            app.ShearHorizontalButton.Text = 'Shear Horizontal';
            app.ShearHorizontalButton.Value = true;
            app.FlexuralButton = uiradiobutton(app.WavemodeButtonGroup);
            app.FlexuralButton.Text = 'Flexural';
            app.LongitudinalButton = uiradiobutton(app.WavemodeButtonGroup);
            app.LongitudinalButton.Text = 'Longitudinal';
            app.TorsionalButton = uiradiobutton(app.WavemodeButtonGroup);
            app.TorsionalButton.Text = 'Torsional';
            app.AllButton = uiradiobutton(app.WavemodeButtonGroup);
            app.AllButton.Text = 'All';
            app.YAxisListBoxLabel = uilabel(app.UIFigure);
            app.YAxisListBoxLabel.Text = 'Y Axis';
            app.YAxisListBox = uilistbox(app.UIFigure);
            app.YAxisListBox.Items = {'Phase Velocity', 'Energy Velocity', 'Attenuation', 'Wavenumber', 'Wavelength'};
            app.YAxisListBox.Value = 'Phase Velocity';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.YAxisUpperLimEditFieldLabel = uilabel(app.UIFigure);
            app.YAxisUpperLimEditFieldLabel.Text = 'YAxis Upper Lim';
            app.YAxisUpperLimEditField = uieditfield(app.UIFigure, 'numeric');
            app.YAxisUpperLimEditField.Value = 11000;
            app.YAxisLowerLimEditFieldLabel = uilabel(app.UIFigure);
            app.YAxisLowerLimEditFieldLabel.Text = 'YAxis Lower Lim';
            app.YAxisLowerLimEditField = uieditfield(app.UIFigure, 'numeric');
            app.YAxisLowerLimEditField.Value = 2500;
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DispersionGraphing

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end